classdef natacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana1                  matlab.ui.Figure
        HighScoresNatacinLabel    matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        AgregarparticipanteTab    matlab.ui.container.Tab
        NombreEditFieldLabel      matlab.ui.control.Label
        NombreEditField           matlab.ui.control.EditField
        EdadEditFieldLabel        matlab.ui.control.Label
        EdadEditField             matlab.ui.control.NumericEditField
        GneroButtonGroup          matlab.ui.container.ButtonGroup
        MujerButton               matlab.ui.control.RadioButton
        HombreButton              matlab.ui.control.RadioButton
        OtroButton                matlab.ui.control.RadioButton
        AgregarButton             matlab.ui.control.StateButton
        ParticipantesTab          matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        AgregarTiempoButton       matlab.ui.control.Button
        AgregarTiempoTab          matlab.ui.container.Tab
        RegresarButton            matlab.ui.control.Button
        AgregarButton_2           matlab.ui.control.Button
        NombreEditField_2Label    matlab.ui.control.Label
        Nombre_tiempo             matlab.ui.control.EditField
        TiempoFormato000000Label  matlab.ui.control.Label
        TiempoEditField           matlab.ui.control.EditField
        TablaGeneral              matlab.ui.container.Tab
        generalTable              matlab.ui.control.Table
        Femenino1217Tab           matlab.ui.container.Tab
        Femenino1217Table         matlab.ui.control.Table
        Masculino1217Tab          matlab.ui.container.Tab
        Masculino1217Table        matlab.ui.control.Table
        Femenino1825Tab           matlab.ui.container.Tab
        Femenino1825Table         matlab.ui.control.Table
        Masculino1825Tab          matlab.ui.container.Tab
        Masculino1825Table        matlab.ui.control.Table
        Femenino4060Tab           matlab.ui.container.Tab
        Femenino4060Table         matlab.ui.control.Table
        Masculino4060Tab          matlab.ui.container.Tab
        Masculino4060Table        matlab.ui.control.Table
        Mixto70Tab                matlab.ui.container.Tab
        Mixto70Table              matlab.ui.control.Table
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
    end

    
    properties (Access = private)
        selectedCell
        NombreSeleccionado
        GeneroSeleccionado
        EdadSeleccionado
            
        filename = 'natacion.xlsx';
        mainTimer=0
        mainloop
        
        genTimer=0
        curly=1
        
        m1217Timer=0
        curlyM1217=1
        
        f1217Timer=0
        curlyF1217=1
        
        f1825Timer=0
        curlyF1825=1
        
        m1825Timer=0
        curlyM1825=1
        
        f4060Timer=0
        curlyF4060=1
        
        m4060Timer=0
        curlyM4060=1
        
        mixto70Timer=0
        curlyMixto70=1
        
    end
    
    methods (Access = private)
        
        function agregarATabla(app,tabla,tempNombre,tempTiempo,timer,sheet,curly)
            
            tempPos=0;
            tempDif=0;
            
            if tempTiempo<=0
                msgbox("Campo vacío",'Error','error');
                return
            end
            if timer>0
                    preName = tabla.Data(:,2);
                    preName = table2array(preName(:,1));
                    
                    preTime = tabla.Data(:,3);
                    preTime=table2array(preTime(:,1));
                    for x=1: length(preName)
                        if curly==1
                            temporal=string(preName{x}');
                            try
                            if temporal==string(tempNombre')
                                curr=timeToSecs(app,preTime(x)); 
                                if curr<tempTiempo
                                    msgbox("Su tiempo pasado era mejor, ese se conservará",'Aviso');
                                    return
                                else
                                    tabla.Data(x,:)=[];
                                end                                       
                            end
                            catch
                                continue
                            end
                        else                     
                            temporal=string(preName{x});
                            %disp(temporal);
                            %disp(string(tempNombre));  
                            try
                            if temporal==string(tempNombre)
                                curr=timeToSecs(app,preTime(x)); 
                                if curr<tempTiempo
                                    if sheet=="Tabla General"
                                        msgbox("Su tiempo pasado era mejor, ese se conservará",'Aviso');                                      
                                    end
                                    app.TiempoEditField.Value="";
                                    return
                                else
                                    tabla.Data(x,:)=[];
                                end                                       
                            end
                            catch
                                continue
                            end
                         end                                    
                     end
            end
                                       
                newData = {tempPos tempNombre char(secsToTime(app,tempTiempo)) tempDif}; %sample data for each column
                tabla.Data = [tabla.Data;newData];
                                
                Name = tabla.Data(:,2);
                Time = tabla.Data(:,3);
                Pos = tabla.Data(:,1);
                Dif = tabla.Data(:,4);               
                
                if timer>0
                    %disp("hola")
                    Name=table2array(Name(:,1));
                    Time=table2array(Time(:,1));
                    Pos=table2array(Pos(:,1));
                    Dif=table2array(Dif(:,1));                                              
                end
                
                accumTimes2=ones(length(Time),1);             
                for x=1: length(Time)   
                    if curly==0
                        accumTimes2(x)=timeToSecs(app,Time(x)); 
                    else
                        accumTimes2(x)=timeToSecs(app,Time{x}); 
                    end
                end 
                
                Time=accumTimes2;
                %disp(Time)
                
                tblA = table(Pos,Name,Time,Dif);
                tblB = sortrows(tblA,'Time','ascend');
                
                Pos=(1:length(tblB.Pos));
                
                accumPos=ones(length(tblB.Pos),1);
                accumPos=string(accumPos);               
                for x=1: length(tblB.Pos)
                    if x==1
                        accumPos(x)='+0.00';
                    else                                         
                        accumPos(x)='+' + string((tblB.Time(x))-(tblB.Time(x-1)));                          
                    end                 
                end
                
                accumTimes=ones(length(tblB.Pos),1);
                accumTimes=string(accumTimes);               
                for x=1: length(tblB.Time)                   
                    accumTimes(x)=secsToTime(app,tblB.Time(x));                   
                end   
                
                tblB=table(Pos',tblB.Name,accumTimes,accumPos); 
                             
                tabla.Data = tblB;
                
                app.TiempoEditField.Value="";
                app.Nombre_tiempo.Value="";
                
                if sheet=="Tabla General"
                    app.genTimer=app.genTimer+1;
                    msgbox("Tiempo agregado!",'Éxito');
                    app.TabGroup.SelectedTab = app.TablaGeneral; 
                end               
                
                if sheet=="Femenino 12-17"
                    app.f1217Timer=app.f1217Timer+1;
                    app.TabGroup.SelectedTab = app.Femenino1217Tab; 
                end
                
                if sheet=="Femenino 18-25"
                    app.f1825Timer=app.f1825Timer+1;    
                    app.TabGroup.SelectedTab = app.Femenino1825Tab;
                end
                
                if sheet=="Femenino 40-60"
                    app.f4060Timer=app.f4060Timer+1;   
                    app.TabGroup.SelectedTab = app.Femenino4060Tab;
                end
                
                if sheet=="Masculino 12-17"
                    app.m1217Timer=app.m1217Timer+1;
                    app.TabGroup.SelectedTab = app.Masculino1217Tab;
                end
                
                if sheet=="Masculino 18-25"
                    app.m1825Timer=app.m1825Timer+1; 
                    app.TabGroup.SelectedTab = app.Masculino1825Tab;
                end
                
                if sheet=="Masculino 40-60"
                    app.m4060Timer=app.m4060Timer+1; 
                    app.TabGroup.SelectedTab = app.Masculino4060Tab;
                end
                
                if sheet=="Mixto 70+"
                    app.mixto70Timer=app.mixto70Timer+1;
                    app.TabGroup.SelectedTab = app.Mixto70Tab;
                end
                
                                                  
                data = tabla.Data; 
                %sheet=tabla.Tag;
                
                writetable(data,app.filename,"Sheet",sheet)
            
        end
        
        function result = timeToSecs(~,date)
            t = datetime(date,'InputFormat','mm:ss.SSS');
            result=((minute(t))*60 + second(t));                                 
        end
        
        function result = secsToTime(~,time)
            mins=fix(time/60);
            secs=mod(time,60);
            tiempoTemporal=string(mins)+':'+string(secs); 
            result=tiempoTemporal;
%             y=datetime(tiempoTemporal, 'InputFormat','mm:ss.SSS');
%             result=((minute(y))*60 + second(y));           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Nombre_tiempo.Editable=false;                                  
            try
                app.UITable.Data = readtable(app.filename,'Sheet','Integrantes');               
            catch
                app.UITable.Data=[];
            end
            
             try
                app.generalTable.Data = readtable(app.filename,'Sheet','Tabla General');
                if char(app.UITable.Data{1,1})==char(app.generalTable.Data{1,1})
                    app.generalTable.Data=[];
                end
             catch
                 app.generalTable.Data=[];
             end
            
            try              
                app.Masculino1217Table.Data=readtable(app.filename,'Sheet','Masculino 12-17');               
                if char(app.UITable.Data{1,1})==char(app.Masculino1217Table.Data{1,1})
                    app.Masculino1217Table.Data=[];
                end               
            catch
                app.Masculino1217Table.Data=[];
            end
            
            try              
                app.Femenino1217Table.Data=readtable(app.filename,'Sheet','Femenino 12-17');               
                if char(app.UITable.Data{1,1})==char(app.Femenino1217Table.Data{1,1})
                    app.Femenino1217Table.Data=[];
                end               
            catch
                app.Femenino1217Table.Data=[];
            end
            
            try              
                app.Femenino1825Table.Data=readtable(app.filename,'Sheet','Femenino 18-25');               
                if char(app.UITable.Data{1,1})==char(app.Femenino1825Table.Data{1,1})
                    app.Femenino1825Table.Data=[];
                end               
            catch
                app.Femenino1825Table.Data=[];
            end
            
            try              
                app.Masculino1825Table.Data=readtable(app.filename,'Sheet','Masculino 18-25');               
                if char(app.UITable.Data{1,1})==char(app.Masculino1825Table.Data{1,1})
                    app.Masculino1825Table.Data=[];
                end               
            catch
                app.Masculino1825Table.Data=[];
            end
            
            try              
                app.Femenino4060Table.Data=readtable(app.filename,'Sheet','Femenino 40-60');               
                if char(app.UITable.Data{1,1})==char(app.Femenino4060Table.Data{1,1})
                    app.Femenino4060Table.Data=[];
                end               
            catch
                app.Femenino4060Table.Data=[];
            end
            
            try              
                app.Masculino4060Table.Data=readtable(app.filename,'Sheet','Masculino 40-60');               
                if char(app.UITable.Data{1,1})==char(app.Masculino4060Table.Data{1,1})
                    app.Masculino4060Table.Data=[];
                end               
            catch
                app.Masculino4060Table.Data=[];
            end
            
            try              
                app.Mixto70Table.Data=readtable(app.filename,'Sheet','Mixto 70+');               
                if char(app.UITable.Data{1,1})==char(app.Mixto70Table.Data{1,1})
                    app.Mixto70Table.Data=[];
                end               
            catch
                app.Mixto70Table.Data=[];
            end
            
            if ~isempty(app.generalTable.Data)
                app.genTimer=20;
                app.curly=0;                     
            end
            
            if ~isempty(app.Masculino1217Table.Data)
                app.m1217Timer=20;
                app.curlyM1217=0;
            end
            
            if ~isempty(app.Femenino1217Table.Data)
                app.f1217Timer=20;
                app.curlyF1217=0;
            end
            
            if ~isempty(app.Femenino1825Table.Data)
                app.f1825Timer=20;
                app.curlyF1825=0;
            end
            
            if ~isempty(app.Femenino4060Table.Data)
                app.f4060Timer=20;
                app.curlyF4060=0;
            end
            
            if ~isempty(app.Masculino1825Table.Data)
                app.m1825Timer=20;
                app.curlyM1825=0;
            end
            
            if ~isempty(app.Masculino4060Table.Data)
                app.m4060Timer=20;
                app.curlyM4060=0;
            end
            
            if ~isempty(app.Mixto70Table.Data)
                app.mixto70Timer=20;
                app.curlyMixto70=0;
            end
            
            if isempty(app.UITable.Data)
                app.mainloop=1;
            else
                app.mainTimer=20;
            end
        end

        % Value changed function: AgregarButton
        function AgregarButtonValueChanged(app, event)
            
            tempNom=app.NombreEditField.Value;
            
            if app.mainTimer>0
                preName = app.UITable.Data(:,1);
                preName=table2array(preName(:,1));            
                for x=1: length(preName)
                    temporal=string(preName{x});
                    %try
                    if temporal==string(tempNom)
                       msgbox("Ese participante ya existe",'Error','error');
                       app.NombreEditField.Value="";
                       app.EdadEditField.Value=0;
                       return;
                    end
                    %catch
                     %   continue
                    %end
                end
            end
            
            
            tempEd=app.EdadEditField.Value;
            if tempNom=="" || tempEd==0
            msgbox("Campo vacío",'Error','error');
            return;                   
            end


            if app.HombreButton.Value==1
                tempGenero='Hombre';
                msgbox(tempNom+" Agregado!",'Éxito');
                %app.TiempoEditField.Value=tempNom+" Agregado!";
            elseif app.MujerButton.Value==1
                tempGenero='Mujer';
                msgbox(tempNom+" Agregada!",'Éxito');
                %app.TiempoEditField.Value=tempNom+" Agregada!";
            elseif app.OtroButton.Value==1
                tempGenero='Otro';
                msgbox(tempNom+" Agregade!",'Éxito');
                %app.TiempoEditField.Value=tempNom+" Agregade!";
            end
            
            app.mainTimer=app.mainTimer+1;
            
            newData = {tempNom tempGenero tempEd}; %sample data for each column
            app.UITable.Data = [app.UITable.Data;newData];
                        
            %app.TiempoEditField.Visible='on';
            
            %Reset values
            app.HombreButton.Value=0;
            app.MujerButton.Value=1;
            app.OtroButton.Value=0;
            app.NombreEditField.Value="";
            app.EdadEditField.Value=0;
            
            data = app.UITable.Data; 
            if app.mainloop==1
               writecell(data,app.filename,"Sheet","Integrantes")
               app.UITable.Data = readtable(app.filename,'Sheet','Integrantes');
               app.mainloop=0;
            else
               writetable(data,app.filename,"Sheet","Integrantes")
            end
                                                               
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            try
                app.selectedCell = event.Indices;   
                row=event.Indices(1,1);
                %col=event.Indices(1,2);
                app.NombreSeleccionado=event.Source.Data(row,1);
                app.GeneroSeleccionado=event.Source.Data(row,2);
                app.EdadSeleccionado=event.Source.Data(row,3);               
            catch
                return
            end
            
        end

        % Button pushed function: AgregarTiempoButton
        function AgregarTiempoButtonPushed(app, event)
            try
                curNom= app.NombreSeleccionado;
                app.Nombre_tiempo.Value=char(curNom{1,:});
            catch
                msgbox("Primero selecciona un participante de la tabla",'Aviso');
                return
            end           
            app.TabGroup.SelectedTab = app.AgregarTiempoTab;           
        end

        % Button pushed function: AgregarButton_2
        function AgregarButton_2Pushed(app, event)
           
            tempNombre=app.Nombre_tiempo.Value;
            tempTiempo=app.TiempoEditField.Value;
            try
                tempTiempo=timeToSecs(app,tempTiempo);
            catch
                msgbox("El formato del tiempo debe de ser: 'minutos:segundos.decimas'             Por ejemplo: 01:32.12",'Error');
                app.TiempoEditField.Value='';
                return               
            end
            
            agregarATabla(app,app.generalTable,tempNombre,tempTiempo,app.genTimer,"Tabla General",app.curly)
            
            currGen=string(char(app.GeneroSeleccionado{1,:}));
            currEdad=int16(char(app.EdadSeleccionado{1,:}));
            
            if currGen == 'Hombre' && currEdad <=17 && currEdad>=12 %#ok<BDSCA> 
                agregarATabla(app,app.Masculino1217Table,tempNombre,tempTiempo,app.m1217Timer,"Masculino 12-17",app.curlyM1217)
            end
            
            if currGen == 'Mujer' && currEdad <=17 && currEdad>=12 %#ok<BDSCA> 
                agregarATabla(app,app.Femenino1217Table,tempNombre,tempTiempo,app.f1217Timer,"Femenino 12-17",app.curlyF1217)
            end
            
            if currGen == 'Mujer' && currEdad >=18 && currEdad<=25 %#ok<BDSCA> 
                agregarATabla(app,app.Femenino1825Table,tempNombre,tempTiempo,app.f1825Timer,"Femenino 18-25",app.curlyF1825)
            end
            
            if currGen == 'Mujer' && currEdad >=40 && currEdad<=60 %#ok<BDSCA> 
                agregarATabla(app,app.Femenino4060Table,tempNombre,tempTiempo,app.f4060Timer,"Femenino 40-60",app.curlyF4060)
            end
            
            if currGen == 'Hombre' && currEdad >=18 && currEdad<=25 %#ok<BDSCA> 
                agregarATabla(app,app.Masculino1825Table,tempNombre,tempTiempo,app.m1825Timer,"Masculino 18-25",app.curlyM1825)
            end
            
            if currGen == 'Hombre' && currEdad >=40 && currEdad<=60 %#ok<BDSCA> 
                agregarATabla(app,app.Masculino4060Table,tempNombre,tempTiempo,app.m4060Timer,"Masculino 40-60",app.curlyM4060)
            end
            
            if currEdad>=70
                agregarATabla(app,app.Mixto70Table,tempNombre,tempTiempo,app.mixto70Timer,"Mixto 70+",app.curlyMixto70)    
            end   
                                  
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
                       
            app.TiempoEditField.Value="";
            app.Nombre_tiempo.Value="";
            app.TabGroup.SelectedTab = app.ParticipantesTab;   
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab==app.AgregarTiempoTab
                app.TabGroup.SelectedTab=app.ParticipantesTab;
                msgbox("Primero selecciona un participante de la tabla",'Aviso');               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ventana1 = uifigure('Visible', 'off');
            app.ventana1.Name = 'Natación VelMar';
            app.HighScoresNatacinLabel = uilabel(app.ventana1);
            app.HighScoresNatacinLabel.Text = 'High Scores Natación';
            app.TabGroup = uitabgroup(app.ventana1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.AgregarparticipanteTab = uitab(app.TabGroup);
            app.AgregarparticipanteTab.Title = 'Agregar participante ';
            app.NombreEditFieldLabel = uilabel(app.AgregarparticipanteTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.AgregarparticipanteTab, 'text');
            app.EdadEditFieldLabel = uilabel(app.AgregarparticipanteTab);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.AgregarparticipanteTab, 'numeric');
            app.GneroButtonGroup = uibuttongroup(app.AgregarparticipanteTab);
            app.GneroButtonGroup.Title = 'Género';
            app.MujerButton = uiradiobutton(app.GneroButtonGroup);
            app.MujerButton.Text = 'Mujer';
            app.MujerButton.Value = true;
            app.HombreButton = uiradiobutton(app.GneroButtonGroup);
            app.HombreButton.Text = 'Hombre';
            app.OtroButton = uiradiobutton(app.GneroButtonGroup);
            app.OtroButton.Text = 'Otro';
            app.AgregarButton = uibutton(app.AgregarparticipanteTab, 'state');
            app.AgregarButton.ValueChangedFcn = createCallbackFcn(app, @AgregarButtonValueChanged, true);
            app.AgregarButton.Text = 'Agregar';
            app.ParticipantesTab = uitab(app.TabGroup);
            app.ParticipantesTab.Title = 'Participantes';
            app.UITable = uitable(app.ParticipantesTab);
            app.UITable.ColumnName = {'Nombre'; 'Género'; 'Edad'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AgregarTiempoButton = uibutton(app.ParticipantesTab, 'push');
            app.AgregarTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarTiempoButtonPushed, true);
            app.AgregarTiempoButton.Text = 'Agregar Tiempo';
            app.AgregarTiempoTab = uitab(app.TabGroup);
            app.AgregarTiempoTab.Title = 'Agregar Tiempo';
            app.RegresarButton = uibutton(app.AgregarTiempoTab, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.AgregarButton_2 = uibutton(app.AgregarTiempoTab, 'push');
            app.AgregarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AgregarButton_2Pushed, true);
            app.AgregarButton_2.Text = 'Agregar';
            app.NombreEditField_2Label = uilabel(app.AgregarTiempoTab);
            app.NombreEditField_2Label.Text = 'Nombre';
            app.Nombre_tiempo = uieditfield(app.AgregarTiempoTab, 'text');
            app.TiempoFormato000000Label = uilabel(app.AgregarTiempoTab);
            app.TiempoFormato000000Label.Text = 'Tiempo  (Formato: 00:00.00)';
            app.TiempoEditField = uieditfield(app.AgregarTiempoTab, 'text');
            app.TablaGeneral = uitab(app.TabGroup);
            app.TablaGeneral.Title = 'Tabla General';
            app.generalTable = uitable(app.TablaGeneral);
            app.generalTable.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.generalTable.RowName = {};
            app.Femenino1217Tab = uitab(app.TabGroup);
            app.Femenino1217Tab.Title = 'Femenino 12-17';
            app.Femenino1217Table = uitable(app.Femenino1217Tab);
            app.Femenino1217Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Femenino1217Table.RowName = {};
            app.Masculino1217Tab = uitab(app.TabGroup);
            app.Masculino1217Tab.Title = 'Masculino 12-17';
            app.Masculino1217Table = uitable(app.Masculino1217Tab);
            app.Masculino1217Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Masculino1217Table.RowName = {};
            app.Femenino1825Tab = uitab(app.TabGroup);
            app.Femenino1825Tab.Title = 'Femenino 18-25';
            app.Femenino1825Table = uitable(app.Femenino1825Tab);
            app.Femenino1825Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Femenino1825Table.RowName = {};
            app.Masculino1825Tab = uitab(app.TabGroup);
            app.Masculino1825Tab.Title = 'Masculino 18-25';
            app.Masculino1825Table = uitable(app.Masculino1825Tab);
            app.Masculino1825Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Masculino1825Table.RowName = {};
            app.Femenino4060Tab = uitab(app.TabGroup);
            app.Femenino4060Tab.Title = 'Femenino 40-60';
            app.Femenino4060Table = uitable(app.Femenino4060Tab);
            app.Femenino4060Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Femenino4060Table.RowName = {};
            app.Masculino4060Tab = uitab(app.TabGroup);
            app.Masculino4060Tab.Title = 'Masculino 40-60';
            app.Masculino4060Table = uitable(app.Masculino4060Tab);
            app.Masculino4060Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Masculino4060Table.RowName = {};
            app.Mixto70Tab = uitab(app.TabGroup);
            app.Mixto70Tab.Title = 'Mixto 70+';
            app.Mixto70Table = uitable(app.Mixto70Tab);
            app.Mixto70Table.ColumnName = {'Posición'; 'Nombre'; 'Tiempo'; 'Diferencia'};
            app.Mixto70Table.RowName = {};
            app.ContextMenu = uicontextmenu(app.ventana1);
            % Assign app.ContextMenu
            app.EdadEditField.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.ventana1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = natacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana1)
        end
    end
end